classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        YLabel_2                      matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        RobotEndEffectorLabel         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        LampLabel                     matlab.ui.control.Label
        XButton_6                     matlab.ui.control.Button
        XButton_5                     matlab.ui.control.Button
        XButton_4                     matlab.ui.control.Button
        XButton_3                     matlab.ui.control.Button
        XButton_2                     matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        TeachLabel                    matlab.ui.control.Label
        Link7Slider                   matlab.ui.control.Slider
        Link7SliderLabel              matlab.ui.control.Label
        Link6Slider                   matlab.ui.control.Slider
        Link6SliderLabel              matlab.ui.control.Label
        Link5Slider                   matlab.ui.control.Slider
        Link5SliderLabel              matlab.ui.control.Label
        Link4Slider                   matlab.ui.control.Slider
        Link4SliderLabel              matlab.ui.control.Label
        Link3Slider                   matlab.ui.control.Slider
        Link3SliderLabel              matlab.ui.control.Label
        Link2Slider                   matlab.ui.control.Slider
        Link2SliderLabel              matlab.ui.control.Label
        Link1Slider                   matlab.ui.control.Slider
        Link1SliderLabel              matlab.ui.control.Label
        SelectRobotButtonGroup        matlab.ui.container.ButtonGroup
        ESTOPButton                   matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        PandaButton                   matlab.ui.control.RadioButton
        LinearUR5Button               matlab.ui.control.RadioButton
        EnterangleindegreesLabel      matlab.ui.control.Label
        AngleinRadiansEditFieldLabel  matlab.ui.control.Label
        AngleinRadiansEditField       matlab.ui.control.NumericEditField
        AngleinDegreesEditFieldLabel  matlab.ui.control.Label
        AngleinDegreesEditField       matlab.ui.control.NumericEditField
        RobotJoggingLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        pandaPos;
        pandaTr;
        ur5Pos;
        ur5Tr;
        self;
        robot = 'ur5';
    end
    
    methods (Access = public)
        function UpdateGUI(app)
            app.pandaPos = app.self.pandaJointAngles;
            app.pandaTr = app.self.pandaEnd;
            app.pandaTr = app.pandaTr.T;
            app.ur5Pos = app.self.ur5JointAngles;
            app.ur5Tr = app.self.ur5End;
            app.ur5Tr = app.ur5Tr.T;
            
            if strcmp(app.robot, 'Panda')
                app.XLabel.Text = num2str(app.pandaTr(1, 4));
                app.YLabel.Text = num2str(app.pandaTr(2, 4));
                app.ZLabel.Text = num2str(app.pandaTr(3, 4));

                pDegrees = rad2deg(app.pandaPos);
                app.Link1Slider.Value = pDegrees(1);
                app.Link2Slider.Value = pDegrees(2);
                app.Link3Slider.Value = pDegrees(3);
                app.Link4Slider.Value = pDegrees(4);
                app.Link5Slider.Value = pDegrees(5);
                app.Link6Slider.Value = pDegrees(6);
                app.Link7Slider.Value = pDegrees(7);
            else
                app.XLabel.Text = ['X = ', num2str(app.ur5Tr(1, 4))];
                app.YLabel.Text = ['Y = ', num2str(app.ur5Tr(2, 4))];
                app.ZLabel.Text = ['Z = ', num2str(app.ur5Tr(3, 4))];

                pDegrees = rad2deg(app.ur5Pos);
                app.Link1Slider.Value = pDegrees(1);
                app.Link2Slider.Value = pDegrees(2);
                app.Link3Slider.Value = pDegrees(3);
                app.Link4Slider.Value = pDegrees(4);
                app.Link5Slider.Value = pDegrees(5);
                app.Link6Slider.Value = pDegrees(6);
                app.Link7Slider.Value = pDegrees(7);

            end
            % app.robotTransformX_KUKA.Text = num2str(app.ur5Tr(1, 4));
            % app.robotTransformY_KUKA.Text = num2str(app.ur5Tr(2, 4));
            % app.robotTransformZ_KUKA.Text = num2str(app.ur5Tr(3, 4));
            
            % rpy = rad2deg(tr2rpy(app.pandaTr));
            % app.robotTransformRoll_UR3.Text = num2str(rpy(1));
            % app.robotTransformPitch_UR3.Text = num2str(rpy(2));
            % app.robotTransformYaw_UR3.Text = num2str(rpy(3));
            
            % rpy = rad2deg(tr2rpy(app.ur5Tr));
            % app.robotTransformRoll_KUKA.Text = num2str(rpy(1));
            % app.robotTransformPitch_KUKA.Text = num2str(rpy(2));
            % app.robotTransformYaw_KUKA.Text = num2str(rpy(3));
            
            %update slider positions
            % pDegrees = rad2deg(app.pandaPos);
            % app.L1Slider_UR3.Value = pDegrees(1);
            % app.L2Slider_UR3.Value = pDegrees(2);
            % app.L3Slider_UR3.Value = pDegrees(3);
            % app.L4Slider_UR3.Value = pDegrees(4);
            % app.L5Slider_UR3.Value = pDegrees(5);
            % app.L6Slider_UR3.Value = pDegrees(6);
            % 
            % pDegrees = rad2deg(app.ur5Pos);
            % app.L1Slider_KUKA.Value = pDegrees(1);
            % app.L2Slider_KUKA.Value = pDegrees(2);
            % app.L3Slider_KUKA.Value = pDegrees(3);
            % app.L4Slider_KUKA.Value = pDegrees(4);
            % app.L5Slider_KUKA.Value = pDegrees(5);
            % app.L6Slider_KUKA.Value = pDegrees(6);
            % app.L7Slider_KUKA.Value = pDegrees(7);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, self)
            app.self = self;
        end

        % Value changed function: AngleinRadiansEditField
        function AngleinRadiansEditFieldValueChanged(app, event)
            value = app.AngleinRadiansEditField.Value;
            app.AngleinDegreesEditField.Value = value * (180/pi)
        end

        % Value changed function: AngleinDegreesEditField
        function AngleinDegreesEditFieldValueChanged(app, event)
            value = app.AngleinDegreesEditField.Value;
            app.AngleinRadiansEditField.Value = value * (pi/180)
        end

        % Callback function: not associated with a component
        function XEditField_11ValueChanged(app, event)
            value = app.XEditField_11.Value;
            app.baseX = value;
        end

        % Callback function: not associated with a component
        function YEditField_11ValueChanged(app, event)
            value = app.YEditField_11.Value;
            app.baseY = value;
        end

        % Callback function: not associated with a component
        function ZEditField_11ValueChanged(app, event)
            value = app.ZEditField_11.Value;
            app.baseZ = value;
        end

        % Selection changed function: SelectRobotButtonGroup
        function SelectRobotButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectRobotButtonGroup.SelectedObject.Text;
            app.robot = selectedButton;
            disp(app.robot)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RobotJoggingLabel = uilabel(app.UIFigure);
            app.RobotJoggingLabel.Text = 'Robot Jogging';
            app.AngleinDegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinDegreesEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinDegreesEditFieldValueChanged, true);
            app.AngleinDegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinDegreesEditFieldLabel.Text = 'Angle in Degrees';
            app.AngleinRadiansEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleinRadiansEditField.ValueChangedFcn = createCallbackFcn(app, @AngleinRadiansEditFieldValueChanged, true);
            app.AngleinRadiansEditFieldLabel = uilabel(app.UIFigure);
            app.AngleinRadiansEditFieldLabel.Text = 'Angle in Radians';
            app.EnterangleindegreesLabel = uilabel(app.UIFigure);
            app.EnterangleindegreesLabel.Text = 'Enter angle in degrees.';
            app.SelectRobotButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectRobotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectRobotButtonGroupSelectionChanged, true);
            app.SelectRobotButtonGroup.Title = 'Select Robot';
            app.LinearUR5Button = uiradiobutton(app.SelectRobotButtonGroup);
            app.LinearUR5Button.Text = 'LinearUR5';
            app.LinearUR5Button.Value = true;
            app.PandaButton = uiradiobutton(app.SelectRobotButtonGroup);
            app.PandaButton.Text = 'Panda';
            app.Image2 = uiimage(app.SelectRobotButtonGroup);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '@Panda', 'panda.jpg');
            app.Image = uiimage(app.SelectRobotButtonGroup);
            app.Image.ImageSource = fullfile(pathToMLAPP, '@LinearUR5custom', 'ur5.jpg');
            app.ESTOPButton = uibutton(app.SelectRobotButtonGroup, 'push');
            app.ESTOPButton.Text = 'E-STOP';
            app.Link1SliderLabel = uilabel(app.UIFigure);
            app.Link1SliderLabel.Text = 'Link 1';
            app.Link1Slider = uislider(app.UIFigure);
            app.Link1Slider.Limits = [-1 1];
            app.Link2SliderLabel = uilabel(app.UIFigure);
            app.Link2SliderLabel.Text = 'Link 2';
            app.Link2Slider = uislider(app.UIFigure);
            app.Link2Slider.Limits = [-1 1];
            app.Link3SliderLabel = uilabel(app.UIFigure);
            app.Link3SliderLabel.Text = 'Link 3';
            app.Link3Slider = uislider(app.UIFigure);
            app.Link3Slider.Limits = [-1 1];
            app.Link4SliderLabel = uilabel(app.UIFigure);
            app.Link4SliderLabel.Text = 'Link 4';
            app.Link4Slider = uislider(app.UIFigure);
            app.Link4Slider.Limits = [-1 1];
            app.Link5SliderLabel = uilabel(app.UIFigure);
            app.Link5SliderLabel.Text = 'Link 5';
            app.Link5Slider = uislider(app.UIFigure);
            app.Link5Slider.Limits = [-1 1];
            app.Link6SliderLabel = uilabel(app.UIFigure);
            app.Link6SliderLabel.Text = 'Link 6';
            app.Link6Slider = uislider(app.UIFigure);
            app.Link6Slider.Limits = [-1 1];
            app.Link7SliderLabel = uilabel(app.UIFigure);
            app.Link7SliderLabel.Text = 'Link 7';
            app.Link7Slider = uislider(app.UIFigure);
            app.Link7Slider.Limits = [-1 1];
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.Text = '+ X';
            app.XButton_2 = uibutton(app.UIFigure, 'push');
            app.XButton_2.Text = '- X';
            app.XButton_3 = uibutton(app.UIFigure, 'push');
            app.XButton_3.Text = '+ X';
            app.XButton_4 = uibutton(app.UIFigure, 'push');
            app.XButton_4.Text = '- X';
            app.XButton_5 = uibutton(app.UIFigure, 'push');
            app.XButton_5.Text = '+ X';
            app.XButton_6 = uibutton(app.UIFigure, 'push');
            app.XButton_6.Text = '- X';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.RobotEndEffectorLabel = uilabel(app.UIFigure);
            app.RobotEndEffectorLabel.Text = 'Robot End Effector';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X = ';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y = ';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z = ';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R = ';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P = ';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end